classdef ValvulasDesbalanceadas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BNCMtodoGrficoUIFigure       matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        VlvulasDesbalanceadasTab     matlab.ui.container.Tab
        UIAxesValvulas               matlab.ui.control.UIAxes
        GradientesparamezclasdeaguasaladaTab  matlab.ui.container.Tab
        UIAxesGradMezclasAguaSalada  matlab.ui.control.UIAxes
        OTISpesodelacolumnadegasTab  matlab.ui.container.Tab
        UIAxesOTIS                   matlab.ui.control.UIAxes
        HTML                         matlab.ui.control.HTML
        TabladeValvulas              matlab.ui.control.Table
        TabladeResultados            matlab.ui.control.Table
        TitulodeMetodo               matlab.ui.control.Label
        PanelInformacion             matlab.ui.container.Panel
        LimpiarButton                matlab.ui.control.Button
        Drop_Down_DensidadRelativa   matlab.ui.control.DropDown
        DensidadrelativadelgasdeinyeccinDropDownLabel  matlab.ui.control.Label
        PorcentajeAgua               matlab.ui.control.NumericEditField
        TemperaturaProfundidadLabel  matlab.ui.control.Label
        CalcularButton               matlab.ui.control.Button
        Pws                          matlab.ui.control.NumericEditField
        GastQobpdLabel_11            matlab.ui.control.Label
        Densidad_Aceite              matlab.ui.control.DropDown
        DensidaddelaceiteAPILabel    matlab.ui.control.Label
        IP                           matlab.ui.control.NumericEditField
        GastQobpdLabel_10            matlab.ui.control.Label
        TCalculada_Taller            matlab.ui.control.NumericEditField
        GastQobpdLabel_9             matlab.ui.control.Label
        PDisponible                  matlab.ui.control.NumericEditField
        GastQobpdLabel_8             matlab.ui.control.Label
        Tfondo                       matlab.ui.control.NumericEditField
        GastQobpdLabel_7             matlab.ui.control.Label
        Twh                          matlab.ui.control.NumericEditField
        GastQobpdLabel_6             matlab.ui.control.Label
        Profundidad                  matlab.ui.control.NumericEditField
        GastQobpdLabel_5             matlab.ui.control.Label
        GradFC                       matlab.ui.control.NumericEditField
        GastQobpdLabel_4             matlab.ui.control.Label
        Pwh                          matlab.ui.control.NumericEditField
        GastQobpdLabel_3             matlab.ui.control.Label
        Pso                          matlab.ui.control.NumericEditField
        GastQobpdLabel_2             matlab.ui.control.Label
        Qo                           matlab.ui.control.NumericEditField
        GastQobpdLabel               matlab.ui.control.Label
    end

    
    methods (Access = private)

        % DIBUJAR GRÁFICA DE GRADIENTES PARA MEZCLAS DE AGUA SALADA
        function dibujarBaseGradientesAguaSalada(app)
        
            ax = app.UIAxesGradMezclasAguaSalada;
        
            cla(ax);
            hold(ax, 'on');
        
            agua = 0:1:100;
        
            api = [10 15 20 25 30 35 40 45];
        
            for i = 1:numel(api)
        
                switch string(api(i))
                    case "10"
                        gradiente = (0.00033 .* agua) + 0.432;
                    case "15"
                        gradiente = (0.00047 .* agua) + 0.418;
                    case "20"
                        gradiente = (0.00061 .* agua) + 0.404;
                    case "25"
                        gradiente = (0.00073 .* agua) + 0.392;
                    case "30"
                        gradiente = (0.00086 .* agua) + 0.379;
                    case "35"
                        gradiente = (0.00097 .* agua) + 0.368;
                    case "40"
                        gradiente = (0.00107 .* agua) + 0.358;
                    case "45"
                        gradiente = (0.00117 .* agua) + 0.348;
                end
        
                plot(ax, agua, gradiente, 'LineWidth', 1, 'DisplayName', sprintf('%d °API', api(i)));
        
            end

            legend(ax, 'Location', 'best', 'FontSize', 8, 'Interpreter', 'none');
            hold(ax, 'off');
        
        end

        % GRÁFICA DE GRADIENTES PARA MEZCLAS DE AGUA SALADA | Obtener factor
        function Figura3k = obtenerFigura3k(app)
    
            agua = app.PorcentajeAgua.Value;
    
            switch app.Densidad_Aceite.Value
                case "10"
                    Figura3k = (0.00033 * agua) + 0.432;
                case "15"
                    Figura3k = (0.00047 * agua) + 0.418;
                case "20"
                    Figura3k = (0.00061 * agua) + 0.404;
                case "25"
                    Figura3k = (0.00073 * agua) + 0.392;
                case "30"
                    Figura3k = (0.00086 * agua) + 0.379;
                case "35"
                    Figura3k = (0.00097 * agua) + 0.368;
                case "40"
                    Figura3k = (0.00107 * agua) + 0.358;
                case "45"
                    Figura3k = (0.00117 * agua) + 0.348;
                otherwise
                    error('No se pudo determinar el gradientes para mezclas de agua salada.');
            end
    
        end

        % ACTUALIZAR GRÁFICA DE GRADIENTES PARA MEZCLAS DE AGUA SALADA
        function actualizarPuntoGradientesAguaSalada(app, Figura3k)
                
                    ax = app.UIAxesGradMezclasAguaSalada;
                
                    % Borra solo resultados anteriores, conserva curvas base
                    delete(findall(ax, 'Tag', 'ResultadoGradAgua'));
                
                    hold(ax, 'on');
                
                    agua = app.PorcentajeAgua.Value;
                    api = str2double(app.Densidad_Aceite.Value);
                
                    % Punto del resultado
                    plot(ax, agua, Figura3k, ...
                        'o', ...
                        'MarkerSize', 4, ...
                        'LineWidth', 1, ...
                        'MarkerFaceColor', [1.00 1.00 1.00], ...
                        'MarkerEdgeColor', [0.00 0.00 0.00], ...
                        'DisplayName', sprintf('Resultado: %d °API, %.0f %% agua', api, agua), ...
                        'Tag', 'ResultadoGradAgua');
                
                    % Línea vertical del resultado
                    xline(ax, agua, '--', ...
                        'Color', [0.00 0.00 0.00], ...
                        'LineWidth', 1, ...
                        'DisplayName', 'Porcentaje de agua seleccionado', ...
                        'Tag', 'ResultadoGradAgua');
                
                    % Línea horizontal del resultado
                    yline(ax, Figura3k, '--', ...
                        'Color', [0.30 0.30 0.30], ...
                        'LineWidth', 1, ...
                        'DisplayName', 'Gradiente calculado', ...
                        'Tag', 'ResultadoGradAgua');
                
                    legend(ax, 'Location', 'best', 'FontSize', 8, 'Interpreter', 'none');
                
                    hold(ax, 'off');
                
                end


        % DIBUJAR GRÁFICA OTIS PESO DE LA COLUMNA DE GAS
        function dibujarBaseOTIS(app)
                
                    ax = app.UIAxesOTIS;
                
                    cla(ax);
                    hold(ax, 'on');
                
                    presionSuperficial = linspace(200, 1200, 300);
                
                    densidades = ["0.6", "0.65", "0.7", "0.8", "0.9", "1.0"];
            
                    for i = 1:numel(densidades)
                
                        densidad = densidades(i);
                
                        switch densidad
                            case "0.6"
                                deltaP = (presionSuperficial + 34.568) ./ 49.383;
                            case "0.65"
                                deltaP = (presionSuperficial + 17.391) ./ 43.478;
                            case "0.7"
                                deltaP = (presionSuperficial + 11.946) ./ 38.536;
                            case "0.8"
                                deltaP = (presionSuperficial - 8.6522) ./ 33.278;
                            case "0.9"
                                deltaP = (presionSuperficial - 14.815) ./ 29.63;
                            case "1.0"
                                deltaP = (presionSuperficial - 21.192) ./ 26.63;
                        end
                
                        plot(ax, deltaP, presionSuperficial, ...
                            'LineWidth', 1, ...
                            'DisplayName', sprintf('Densidad relativa %.2g', str2double(densidad)));
                
                    end
                
                    legend(ax, 'Location', 'best', 'FontSize', 8, 'Interpreter', 'none');
                    hold(ax, 'off');
                
                end

        % GRÁFICA OTIS PESO DE LA COLUMNA DE GAS | Obtener DeltaP
        function DeltaP = obtenerDeltaP(app, presionBase)
    
            switch app.Drop_Down_DensidadRelativa.Value
                case "0.6"
                    DeltaP = (presionBase + 34.568) / 49.383;
                case "0.65"
                    DeltaP = (presionBase + 17.391) / 43.478;
                case "0.7"
                    DeltaP = (presionBase + 11.946) / 38.536;
                case "0.8"
                    DeltaP = (presionBase - 8.6522) / 33.278;
                case "0.9"
                    DeltaP = (presionBase - 14.815) / 29.63;
                case "1.0"
                    DeltaP = (presionBase - 21.192) / 26.63;
                otherwise
                    error('No se pudo determinar el peso de la columna de gas.');
            end
    
        end

        % ACTUALIZAR GRÁFICA OTIS PESO DE LA COLUMNA DE GAS
        function actualizarPuntoOTIS(app, DeltaP_Pso, DeltaP_Disp)
        
            ax = app.UIAxesOTIS;
        
            % Borra solo resultados anteriores, conserva curvas base
            delete(findall(ax, 'Tag', 'ResultadoOTIS'));
        
            hold(ax, 'on');
        
            % Resultado
            plot(ax, DeltaP_Pso, app.Pso.Value, ...
                'o', ...
                'MarkerSize', 4, ...
                'LineWidth', 1, ...
                'MarkerFaceColor', [0.85 0.33 0.10], ...
                'MarkerEdgeColor', [0 0 0], ...
                'DisplayName', 'Resultado presión de operación', ...
                'Tag', 'ResultadoOTIS');
        
            xline(ax, DeltaP_Pso, '--', ...
                'Color', [0.85 0.33 0.10], ...
                'LineWidth', 1, ...
                'DisplayName', 'DeltaP operación', ...
                'Tag', 'ResultadoOTIS');
        
            yline(ax, app.Pso.Value, '--', ...
                'Color', [0.85 0.33 0.10], ...
                'LineWidth', 1, ...
                'DisplayName', 'Presión de operación', ...
                'Tag', 'ResultadoOTIS');
        
            % Resultado de presión disponible
            plot(ax, DeltaP_Disp, app.PDisponible.Value, ...
                's', ...
                'MarkerSize', 4, ...
                'LineWidth', 1, ...
                'MarkerFaceColor', [0.47 0.67 0.19], ...
                'MarkerEdgeColor', [0 0 0], ...
                'DisplayName', 'Resultado presión disponible', ...
                'Tag', 'ResultadoOTIS');
        
            xline(ax, DeltaP_Disp, ':', ...
                'Color', [0.47 0.67 0.19], ...
                'LineWidth', 1, ...
                'DisplayName', 'DeltaP disponible', ...
                'Tag', 'ResultadoOTIS');
        
            yline(ax, app.PDisponible.Value, ':', ...
                'Color', [0.47 0.67 0.19], ...
                'LineWidth', 1, ...
                'DisplayName', 'Presión disponible', ...
                'Tag', 'ResultadoOTIS');
        
        
            legend(ax, 'Location', 'best', 'FontSize', 8, 'Interpreter', 'none');
            hold(ax, 'off');
        
        end

            
        % TABLA DE RESULTADOS GENERALES
        function cargarTablaResultados(app, resultados)
    
            datos = {
                'Pwf [psi]',                          sprintf('%.2f', resultados.Pwf);
                'Nivel dinámico [ft]',                sprintf('%.2f', resultados.NivelDinamico);
                'Temperatura real [°F]',              sprintf('%.2f', resultados.Treal);
                'ΔP corregida operación [psi/ft]',    sprintf('%.4f', resultados.DeltaPCorregidaPso);
                'Pso máxima [psi]',                   sprintf('%.2f', resultados.PsoMax);
                'ΔP corregida disponible [psi/ft]',   sprintf('%.4f', resultados.DeltaPCorregidaDisp);
                'P disponible máxima [psi]',          sprintf('%.2f', resultados.PDisponibleMax);
            };
    
            app.TabladeResultados.Data = datos;
    
        end
    
        % TABLA DE VÁLVULAS
        function cargarTablaValvulas(app, tablaValvulas)
    
            app.TabladeValvulas.Data = tablaValvulas;
    
        end


        % LIMPIEZA DE RESULTADOS
        function limpiarSalida(app)
        
            % Limpiar gráfica principal de válvulas
            delete(allchild(app.UIAxesValvulas));
            legend(app.UIAxesValvulas, 'off');
        
            app.UIAxesValvulas.YLim = [0 app.Profundidad.Value];
            app.UIAxesValvulas.YTick = 0:1000:app.Profundidad.Value;
            app.UIAxesValvulas.YTickLabel = string(0:1000:app.Profundidad.Value);
        
            app.UIAxesValvulas.XLim = [0 app.Pws.Value];
            app.UIAxesValvulas.XTick = 0:500:app.Pws.Value;
            app.UIAxesValvulas.XTickLabel = string(0:500:app.Pws.Value);
        
            % Limpiar resultados gráficas gradientes de agua salada y OTIS
            % No borra las líneas base de las cartas
            delete(findall(app.UIAxesGradMezclasAguaSalada, 'Tag', 'ResultadoGradAgua'));
            delete(findall(app.UIAxesOTIS, 'Tag', 'ResultadoOTIS'));
        
            % Limpiar tablas
            app.TabladeResultados.Data = cell(0, 2);
            app.TabladeValvulas.Data = cell(0, 7);
        
            % Limpiar HTML estado mecánico
            app.HTML.Data = struct( 'profundidadMax', app.Profundidad.Value, 'valvulas', [] );
        
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            rutaHTML = "D:\Mis Proyectos\Programas Ing. Petrolera\Sistemas Artificiales de Producción\BNC Válvulas Desbalanceadas\Estado mecánico BNC válvulas desbalanceadas.html";
        
            if isfile(rutaHTML)

                app.HTML.HTMLSource = rutaHTML;
                app.HTML.Data = struct( 'profundidadMax', app.Profundidad.Value, 'valvulas', []  );

            else
                uialert(app.BNCMtodoGrficoUIFigure, ...
                    "No se encontró el archivo HTML del estado mecánico.", ...
                    "Archivo no encontrado");
            end

            % Después de cargar el HTML, cargar base de gráficas
            app.dibujarBaseGradientesAguaSalada();
            app.dibujarBaseOTIS();

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            try
                % Validaciones
                if app.IP.Value <= 0
                    uialert(app.BNCMtodoGrficoUIFigure, 'El índice de productividad IP debe ser mayor que cero.', 'Dato inválido');
                    return;
                end
        
                if app.Profundidad.Value <= 0
                    uialert(app.BNCMtodoGrficoUIFigure, 'La profundidad debe ser mayor que cero.', 'Dato inválido');
                    return;
                end
        
                if app.GradFC.Value <= 0
                    uialert(app.BNCMtodoGrficoUIFigure, 'El gradiente del fluido de control debe ser mayor que cero.', 'Dato inválido');
                    return;
                end
        
                if app.Pws.Value <= 0
                    uialert(app.BNCMtodoGrficoUIFigure, 'La presión estática Pws debe ser mayor que cero.', 'Dato inválido');
                    return;
                end
        
                % Limpiar y preparar gráfica principal
                app.limpiarSalida();                    % Limpia gráfica, tablas y HTML
                hold(app.UIAxesValvulas, 'on');         % Permite dibujar varias curvas
        
                % 1) Presión de fondo fluyente Pwf
                Pwf = app.Pws.Value - (app.Qo.Value / app.IP.Value);
        
                % 2) Nivel dinámico
                Figura3k = app.obtenerFigura3k();

                app.actualizarPuntoGradientesAguaSalada(Figura3k);
        
                Nivel_Dinamico = app.Profundidad.Value - (Pwf / Figura3k);
        
                m_ND = (app.Profundidad.Value - Nivel_Dinamico) / Pwf;
        
                VectorNDx = linspace(0, Pwf, 200)';
                VectorNDY = (m_ND * VectorNDx) + Nivel_Dinamico;
        
                plot(app.UIAxesValvulas, VectorNDx, VectorNDY, ...
                    'LineWidth', 1.5, ...
                    'LineStyle', '-', ...
                    'Color', [0.00 0.45 0.74], ...
                    'DisplayName', 'Nivel dinámico');
        
                % 3) Gradiente de presión de operación
                DeltaP_Pso = app.obtenerDeltaP(app.Pso.Value);
        
                Tcalculada = (100 + (70 + (1.6 * (app.Profundidad.Value / 100)))) / 2;
        
                Treal = (app.Twh.Value + app.Tfondo.Value) / 2;
        
                DeltaPCorregidaPso = (DeltaP_Pso / 1000) * ((Tcalculada + 460) / (Treal + 460));
        
                PsoMax = app.Pso.Value + (app.Profundidad.Value * DeltaPCorregidaPso);
        
                m_Pso = app.Profundidad.Value / (PsoMax - app.Pso.Value);
        
                XVectorPso = linspace(app.Pso.Value, PsoMax, 200)';
                YVectorPso = (m_Pso * XVectorPso) - (m_Pso * app.Pso.Value);
        
                plot(app.UIAxesValvulas, XVectorPso, YVectorPso, ...
                    'LineWidth', 1.5, ...
                    'LineStyle', '--', ...
                    'Color', [0.85 0.33 0.10], ...
                    'DisplayName', 'Pso diseño');
        
                % 4) Gradiente de presión disponible
                DeltaP_Disp = app.obtenerDeltaP(app.PDisponible.Value);

                app.actualizarPuntoOTIS(DeltaP_Pso, DeltaP_Disp);
        
                DeltaPCorregidaDisp = (DeltaP_Disp / 1000) * ((Tcalculada + 460) / (Treal + 460));
        
                PDisponibleMax = app.PDisponible.Value + (app.Profundidad.Value * DeltaPCorregidaDisp);
        
                m_PDisponible = app.Profundidad.Value / (PDisponibleMax - app.PDisponible.Value);
        
                XVectorPDisponible = linspace(app.PDisponible.Value, PDisponibleMax, 200)';
                YVectorPDisponible = (m_PDisponible * XVectorPDisponible) - (m_PDisponible * app.PDisponible.Value);
        
                plot(app.UIAxesValvulas, XVectorPDisponible, YVectorPDisponible, ...
                    'LineWidth', 1.5, ...
                    'LineStyle', '-.', ...
                    'Color', [0.47 0.67 0.19], ...
                    'DisplayName', 'Presión disponible');
        
                % 5) Punto de balance
                XCoordenada_PuntoBalance = (-(m_Pso * app.Pso.Value) - Nivel_Dinamico) / (m_ND - m_Pso);
                YCoordenada_PuntoBalance = (m_ND * XCoordenada_PuntoBalance) + Nivel_Dinamico;
        
                plot(app.UIAxesValvulas, XCoordenada_PuntoBalance, YCoordenada_PuntoBalance, ...
                    'o', ...
                    'MarkerSize', 4, ...
                    'LineWidth', 1, ...
                    'MarkerEdgeColor', [0.49 0.18 0.56], ...
                    'MarkerFaceColor', [0.49 0.18 0.56], ...
                    'DisplayName', 'Punto de balance');
        
                text(app.UIAxesValvulas, ...
                    XCoordenada_PuntoBalance, ...
                    YCoordenada_PuntoBalance, ...
                    '  PB', ...
                    'FontWeight', 'bold', ...
                    'Color', [0.49 0.18 0.56]);
        
                % 6) Punto de inyección de gas
                XCoordenada_PuntodeInyeccionGas = XCoordenada_PuntoBalance - 100;
                YCoordenada_PuntodeInyeccionGas = (m_ND * XCoordenada_PuntodeInyeccionGas) + Nivel_Dinamico;
        
                plot(app.UIAxesValvulas, XCoordenada_PuntodeInyeccionGas, YCoordenada_PuntodeInyeccionGas, ...
                    's', ...
                    'MarkerSize', 4, ...
                    'LineWidth', 1, ...
                    'MarkerEdgeColor', [0.93 0.69 0.13], ...
                    'MarkerFaceColor', [0.93 0.69 0.13], ...
                    'DisplayName', 'Punto de inyección');
        
                text(app.UIAxesValvulas, ...
                    XCoordenada_PuntodeInyeccionGas, ...
                    YCoordenada_PuntodeInyeccionGas, ...
                    '  PI', ...
                    'FontWeight', 'bold', ...
                    'Color', [0.70 0.45 0.00]);
        
                % 7) Relación Gas-Líquido
                m_Pwh = YCoordenada_PuntodeInyeccionGas / (XCoordenada_PuntodeInyeccionGas - app.Pwh.Value);
        
                XVector_RelacionGasLiquido = linspace(app.Pwh.Value, XCoordenada_PuntodeInyeccionGas, 200)';
                YVector_RelacionGasLiquido = m_Pwh * (XVector_RelacionGasLiquido - app.Pwh.Value);
        
                plot(app.UIAxesValvulas, XVector_RelacionGasLiquido, YVector_RelacionGasLiquido, ...
                    'LineWidth', 1.5, ...
                    'LineStyle', '-', ...
                    'Color', [0.30 0.75 0.93], ...
                    'DisplayName', 'Pt fluyendo');
        
                % 8) Presión de T.P. de diseño
                P_TP_Diseno = app.Pwh.Value + 200;
        
                m_PTP_Diseno = YCoordenada_PuntodeInyeccionGas / (XCoordenada_PuntodeInyeccionGas - P_TP_Diseno);
        
                XVector_P_TP_Diseno = linspace(P_TP_Diseno, XCoordenada_PuntodeInyeccionGas, 200)';
                YVector_P_TP_Diseno = m_PTP_Diseno * (XVector_P_TP_Diseno - P_TP_Diseno);
        
                plot(app.UIAxesValvulas, XVector_P_TP_Diseno, YVector_P_TP_Diseno, ...
                    'LineWidth', 1.5, ...
                    'LineStyle', '--', ...
                    'Color', [0.64 0.08 0.18], ...
                    'DisplayName', 'Pt diseño');
        
                % 9) Gradiente del fluido de control
                Interseccion_LGFC_con_PDisp = (app.Pwh.Value - (app.GradFC.Value * m_PDisponible * app.PDisponible.Value)) / (1 - (app.GradFC.Value * m_PDisponible));
        
                XVector_LGFC = linspace(app.Pwh.Value, Interseccion_LGFC_con_PDisp, 200);
                YVector_LGFC =  (XVector_LGFC - app.Pwh.Value) / app.GradFC.Value;
        
                plot(app.UIAxesValvulas, XVector_LGFC, YVector_LGFC, ...
                    'LineWidth', 1.5, ...
                    'LineStyle', ':', ...
                    'Color', [0.00 0.00 0.00], ...
                    'DisplayName', 'Grad. del fluido de control');
        
                % 10) Primera válvula
                YCoordena_Valvula1 = (Interseccion_LGFC_con_PDisp - app.Pwh.Value) / app.GradFC.Value;
        
                No_Valvula = 1;
        
                plot(app.UIAxesValvulas, Interseccion_LGFC_con_PDisp, YCoordena_Valvula1, ...
                    'v', ...                                    % Forma del marcador
                    'MarkerSize', 4, ...                        % Tamaño
                    'LineWidth', 1, ...                         % Grosor del borde
                    'MarkerEdgeColor', [0.00 0.00 0.00], ...    % Color del borde
                    'MarkerFaceColor', [0.00 0.00 0.00], ...    % Color de relleno
                    'DisplayName', 'Válvula');
        
                text(app.UIAxesValvulas, ...
                    Interseccion_LGFC_con_PDisp, ...
                    YCoordena_Valvula1, ...
                    '  V1', ...
                    'FontWeight', 'bold', ...
                    'Color', [0.00 0.00 0.00]);
        
                % Máximo de válvulas permitido
                maxValvulas = 15;
                
                % Tabla de válvulas preasignada
                tablaValvulas = cell(maxValvulas, 7);
                
                % Datos para el HTML externo preasignados
                valvulasHTML = repmat(struct('numero', [], 'profundidad', []), maxValvulas, 1);
                        
                PtDiseno_Valvula = (YCoordena_Valvula1 / m_PTP_Diseno) + P_TP_Diseno;
        
                PtFluyendo_Valvula = (YCoordena_Valvula1 / m_Pwh) + app.Pwh.Value;
        
                PsoDiseno_Valvula = (YCoordena_Valvula1 + (m_Pso * app.Pso.Value)) / m_Pso;
        
                DeltaP_Valvula = PsoDiseno_Valvula - PtDiseno_Valvula;

                TValvula = app.Twh.Value + ...
                    (YCoordena_Valvula1 / app.Profundidad.Value) * ...
                    (app.Tfondo.Value - app.Twh.Value);
        
                tablaValvulas(No_Valvula,:) = {
                    No_Valvula, ...
                    sprintf('%.2f', YCoordena_Valvula1), ...
                    sprintf('%.2f', TValvula), ...
                    sprintf('%.2f', PtDiseno_Valvula), ...
                    sprintf('%.2f', PtFluyendo_Valvula), ...
                    sprintf('%.2f', PsoDiseno_Valvula), ...
                    sprintf('%.2f', DeltaP_Valvula)
                };
                
                valvulasHTML(No_Valvula).numero = No_Valvula;
                valvulasHTML(No_Valvula).profundidad = YCoordena_Valvula1;
                
                valvulasHTML(end+1).numero = No_Valvula;
                valvulasHTML(end).profundidad = YCoordena_Valvula1;
                        
                % 11) Cálculo de las demás válvulas
                m_LGFC = YCoordena_Valvula1 / (Interseccion_LGFC_con_PDisp - app.Pwh.Value);
        
                y = YCoordena_Valvula1;
                x = Interseccion_LGFC_con_PDisp;
        
                while YCoordenada_PuntodeInyeccionGas > y
        
                    % Desplazamiento horizontal hacia Pt diseño
                    x1 = x;
                    x = (y / m_PTP_Diseno) + P_TP_Diseno;
        
                    line(app.UIAxesValvulas, [x1, x], [y, y], ...
                        'LineStyle', '-', ...
                        'Color', [0.45 0.45 0.45], ...
                        'LineWidth', 1.0, ...
                        'HandleVisibility', 'off');
        
                    plot(app.UIAxesValvulas, x, y, ...
                        '.', ...
                        'MarkerSize', 4, ...
                        'Color', [0.64 0.08 0.18], ...
                        'HandleVisibility', 'off');
        
                    % Desplazamiento hacia Pso diseño
                    x1 = x;
                    y1 = y;
        
                    y = (m_LGFC * (Interseccion_LGFC_con_PDisp - x)) + y;
                    x = (y + (m_Pso * app.Pso.Value)) / m_Pso;
        
                    line(app.UIAxesValvulas, [x1, x], [y1, y], ...
                        'LineStyle', '-', ...
                        'Color', [0.45 0.45 0.45], ...
                        'LineWidth', 1.0, ...
                        'HandleVisibility', 'off');
        
                    No_Valvula = No_Valvula + 1;
        
                    plot(app.UIAxesValvulas, x, y, ...
                        'v', ...
                        'MarkerSize', 4, ...
                        'LineWidth', 1, ...
                        'MarkerEdgeColor', [0.00 0.00 0.00], ...
                        'MarkerFaceColor', [0.00 0.00 0.00], ...
                        'HandleVisibility', 'off');
        
                    text(app.UIAxesValvulas, ...
                        x, ...
                        y, ...
                        sprintf('  V%d', No_Valvula), ...
                        'FontWeight', 'bold', ...
                        'Color', [0.00 0.00 0.00]);
        
                    % Resultados de válvula
                    PtDiseno_Valvula = (y / m_PTP_Diseno) + P_TP_Diseno;
        
                    PtFluyendo_Valvula = (y / m_Pwh) + app.Pwh.Value;
        
                    PsoDiseno_Valvula = (y + (m_Pso * app.Pso.Value)) / m_Pso;
        
                    DeltaP_Valvula = PsoDiseno_Valvula - PtDiseno_Valvula;

                    TValvula = app.Twh.Value + (y / app.Profundidad.Value) * (app.Tfondo.Value - app.Twh.Value);
                        
                    tablaValvulas(No_Valvula,:) = {
                        No_Valvula, ...
                        sprintf('%.2f', y), ...
                        sprintf('%.2f', TValvula), ...
                        sprintf('%.2f', PtDiseno_Valvula), ...
                        sprintf('%.2f', PtFluyendo_Valvula), ...
                        sprintf('%.2f', PsoDiseno_Valvula), ...
                        sprintf('%.2f', DeltaP_Valvula)
                    };
                    
                    valvulasHTML(No_Valvula).numero = No_Valvula;
                    valvulasHTML(No_Valvula).profundidad = y;
        
                    % Control de seguridad
                    if No_Valvula >= maxValvulas
                        break;
                    end
        
                end
        
                % 12) Tabla de resultados generales
                resultados.Pwf = Pwf;
                resultados.NivelDinamico = Nivel_Dinamico;
                resultados.Tcalculada = Tcalculada;
                resultados.Treal = Treal;
                resultados.DeltaPCorregidaPso = DeltaPCorregidaPso;
                resultados.PsoMax = PsoMax;
                resultados.DeltaPCorregidaDisp = DeltaPCorregidaDisp;
                resultados.PDisponibleMax = PDisponibleMax;

                % Recortar solo las válvulas realmente calculadas
                tablaValvulas = tablaValvulas(1:No_Valvula, :);
                valvulasHTML = valvulasHTML(1:No_Valvula);
                        
                app.cargarTablaResultados(resultados);
                app.cargarTablaValvulas(tablaValvulas);
                app.HTML.Data = struct(  'profundidadMax', app.Profundidad.Value,  'valvulas', valvulasHTML );

                app.UIAxesValvulas.XLim = [0 app.Pws.Value];
                app.UIAxesValvulas.YLim = [0 app.Profundidad.Value];
                legend(app.UIAxesValvulas, 'Location', 'best', 'FontSize', 8, 'Interpreter', 'none');
                hold(app.UIAxesValvulas, 'off');
        
            catch ME
        
                hold(app.UIAxesValvulas, 'off');
                uialert(app.BNCMtodoGrficoUIFigure, ME.message, 'Error al graficar');
        
            end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            
            app.limpiarSalida();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BNCMtodoGrficoUIFigure = uifigure('Visible', 'off');
            app.BNCMtodoGrficoUIFigure.Name = 'BNC | Método Gráfico';
            app.GridLayout = uigridlayout(app.BNCMtodoGrficoUIFigure);
            app.PanelInformacion = uipanel(app.GridLayout);
            app.PanelInformacion.Title = 'Información';
            app.GastQobpdLabel = uilabel(app.PanelInformacion);
            app.GastQobpdLabel.Text = 'Gasto, Qo, [bpd]:';
            app.Qo = uieditfield(app.PanelInformacion, 'numeric');
            app.Qo.Value = 600;
            app.GastQobpdLabel_2 = uilabel(app.PanelInformacion);
            app.GastQobpdLabel_2.Text = 'Presión de operación, Pso, [psi]:';
            app.Pso = uieditfield(app.PanelInformacion, 'numeric');
            app.Pso.Value = 850;
            app.GastQobpdLabel_3 = uilabel(app.PanelInformacion);
            app.GastQobpdLabel_3.Text = 'Presión en cabezal, Pwh, [psi]:';
            app.Pwh = uieditfield(app.PanelInformacion, 'numeric');
            app.Pwh.Value = 100;
            app.GastQobpdLabel_4 = uilabel(app.PanelInformacion);
            app.GastQobpdLabel_4.Text = 'Gradiente del fluido de control [psi/ft]:';
            app.GradFC = uieditfield(app.PanelInformacion, 'numeric');
            app.GradFC.Value = 0.468;
            app.GastQobpdLabel_5 = uilabel(app.PanelInformacion);
            app.GastQobpdLabel_5.Text = 'Profundidad [ft]:';
            app.Profundidad = uieditfield(app.PanelInformacion, 'numeric');
            app.Profundidad.Value = 8000;
            app.GastQobpdLabel_6 = uilabel(app.PanelInformacion);
            app.GastQobpdLabel_6.Text = 'Temperatura en cabezal, Twh [°F]:';
            app.Twh = uieditfield(app.PanelInformacion, 'numeric');
            app.Twh.Value = 80;
            app.GastQobpdLabel_7 = uilabel(app.PanelInformacion);
            app.GastQobpdLabel_7.Text = 'Temperatura de fondo, Tfondo [°F]:';
            app.Tfondo = uieditfield(app.PanelInformacion, 'numeric');
            app.Tfondo.Value = 170;
            app.GastQobpdLabel_8 = uilabel(app.PanelInformacion);
            app.GastQobpdLabel_8.Text = 'ΔP en la válvula operante, PDisp [psi]:';
            app.PDisponible = uieditfield(app.PanelInformacion, 'numeric');
            app.PDisponible.Value = 1000;
            app.GastQobpdLabel_9 = uilabel(app.PanelInformacion);
            app.GastQobpdLabel_9.Text = 'T. Calculada en el taller, [°F]:';
            app.TCalculada_Taller = uieditfield(app.PanelInformacion, 'numeric');
            app.TCalculada_Taller.Value = 60;
            app.GastQobpdLabel_10 = uilabel(app.PanelInformacion);
            app.GastQobpdLabel_10.Text = 'IP, [bpd/psi]:';
            app.IP = uieditfield(app.PanelInformacion, 'numeric');
            app.IP.Value = 4;
            app.DensidaddelaceiteAPILabel = uilabel(app.PanelInformacion);
            app.DensidaddelaceiteAPILabel.Text = 'Densidad del aceite, [°API]:';
            app.Densidad_Aceite = uidropdown(app.PanelInformacion);
            app.Densidad_Aceite.Items = {'10', '15', '20', '25', '30', '35', '40', '45'};
            app.Densidad_Aceite.Value = '35';
            app.GastQobpdLabel_11 = uilabel(app.PanelInformacion);
            app.GastQobpdLabel_11.Text = 'Presión de fondo estatica, Pws [psi]:';
            app.Pws = uieditfield(app.PanelInformacion, 'numeric');
            app.Pws.Value = 1900;
            app.CalcularButton = uibutton(app.PanelInformacion, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.TemperaturaProfundidadLabel = uilabel(app.PanelInformacion);
            app.TemperaturaProfundidadLabel.Text = 'Porcentaje de agua, [%]:';
            app.PorcentajeAgua = uieditfield(app.PanelInformacion, 'numeric');
            app.PorcentajeAgua.Limits = [0 100];
            app.PorcentajeAgua.Value = 50;
            app.DensidadrelativadelgasdeinyeccinDropDownLabel = uilabel(app.PanelInformacion);
            app.DensidadrelativadelgasdeinyeccinDropDownLabel.Text = 'Densidad relativa del gas de inyección:';
            app.Drop_Down_DensidadRelativa = uidropdown(app.PanelInformacion);
            app.Drop_Down_DensidadRelativa.Items = {'0.6', '0.65', '0.7', '0.8', '0.9', '1.0'};
            app.Drop_Down_DensidadRelativa.Value = '0.65';
            app.LimpiarButton = uibutton(app.PanelInformacion, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.TitulodeMetodo = uilabel(app.GridLayout);
            app.TitulodeMetodo.Text = 'Bombeo Neumático Continuo con Válvulas Desbalanceadas';
            app.TabladeResultados = uitable(app.GridLayout);
            app.TabladeResultados.ColumnName = {'Parámetro'; 'Valor'};
            app.TabladeResultados.RowName = {};
            app.TabladeValvulas = uitable(app.GridLayout);
            app.TabladeValvulas.ColumnName = {'Válvula'; 'Profundidad [ft]'; 'Temperatura [°F]'; 'Pt diseño [psi]'; 'Pt fluyendo [psi]'; 'Pso diseño [psi]'; 'ΔP corregida [psi]'};
            app.TabladeValvulas.RowName = {};
            app.HTML = uihtml(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.VlvulasDesbalanceadasTab = uitab(app.TabGroup);
            app.VlvulasDesbalanceadasTab.Title = 'Válvulas Desbalanceadas';
            app.UIAxesValvulas = uiaxes(app.VlvulasDesbalanceadasTab);
            title(app.UIAxesValvulas, 'Método Gráfico')
            xlabel(app.UIAxesValvulas, 'Presión [psi]')
            ylabel(app.UIAxesValvulas, 'Profundidad [ft]')
            app.UIAxesValvulas.YDir = 'reverse';
            app.UIAxesValvulas.XAxisLocation = 'top';
            app.UIAxesValvulas.ZTick = [];
            app.GradientesparamezclasdeaguasaladaTab = uitab(app.TabGroup);
            app.GradientesparamezclasdeaguasaladaTab.Title = 'Gradientes para mezclas de agua salada';
            app.UIAxesGradMezclasAguaSalada = uiaxes(app.GradientesparamezclasdeaguasaladaTab);
            title(app.UIAxesGradMezclasAguaSalada, 'Gradientes para mezclas de agua salada')
            xlabel(app.UIAxesGradMezclasAguaSalada, 'Porciento de agua salada')
            ylabel(app.UIAxesGradMezclasAguaSalada, 'Gradiente lb/pg/ft')
            zlabel(app.UIAxesGradMezclasAguaSalada, 'Z')
            app.UIAxesGradMezclasAguaSalada.XLim = [0 100];
            app.UIAxesGradMezclasAguaSalada.YLim = [0.36 0.5];
            app.UIAxesGradMezclasAguaSalada.YDir = 'reverse';
            app.OTISpesodelacolumnadegasTab = uitab(app.TabGroup);
            app.OTISpesodelacolumnadegasTab.Title = 'OTIS: peso de la columna de gas';
            app.UIAxesOTIS = uiaxes(app.OTISpesodelacolumnadegasTab);
            title(app.UIAxesOTIS, 'OTIS: peso de la columna de gas')
            xlabel(app.UIAxesOTIS, 'Presión [psi / 1000 ft]')
            ylabel(app.UIAxesOTIS, 'Presión en superficie [psi]')
            zlabel(app.UIAxesOTIS, 'Z')
            app.UIAxesOTIS.XLim = [0 70];
            app.UIAxesOTIS.YLim = [200 1200];
            app.UIAxesOTIS.XTick = [0 10 20 30 40 50 60 70];
            app.UIAxesOTIS.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'};
            app.UIAxesOTIS.YTick = [200 300 400 500 600 700 800 900 1000 1100 1200];
            % Show the figure after all components are created
            app.BNCMtodoGrficoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ValvulasDesbalanceadas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BNCMtodoGrficoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BNCMtodoGrficoUIFigure)
        end
    end
end